classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MaterialTypeDropDown       matlab.ui.control.DropDown
        FullyReversedLoadingLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        CheckFatigueButton         matlab.ui.control.Button
        CyclesValuesEditField_6    matlab.ui.control.NumericEditField
        CyclesValuesEditField_5    matlab.ui.control.NumericEditField
        CyclesValuesEditField_4    matlab.ui.control.NumericEditField
        CyclesLabel                matlab.ui.control.Label
        StressValuesEditField_3    matlab.ui.control.NumericEditField
        StressValuesEditField_2    matlab.ui.control.NumericEditField
        StressValuesLabel          matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        FatigueLifeEstimatorLabel  matlab.ui.control.Label
        StressValuesEditField      matlab.ui.control.NumericEditField
        MaterialsListBox           matlab.ui.control.ListBox
        MaterialsListBoxLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        All_Data %Table with CSV data
    end

    
    methods (Access = public)
        
        function loadMaterialList(app)
            app.All_Data = readtable('S-N curve Data3.csv');
            materials = unique(app.All_Data{:, 1});
            app.MaterialsListBox.Items = materials;
        end

        function loadMaterialType(app)
            material_types = unique(app.All_Data{:, 2});
            app.MaterialTypeDropDown.Items = material_types;
        end

        function match_MT_to_ML(app)
            selected_material_type = app.MaterialTypeDropDown.Value;
            
            % Logical indexing to filter rows with the selected material type
            isMatch = strcmp(app.All_Data{:, 2}, selected_material_type);
            
            % Extract corresponding materials
            filtered_materials = app.All_Data{isMatch, 1};
            
            % Get unique material names
            new_materials = unique(filtered_materials);
            
            % Update the ListBox
            app.MaterialsListBox.Items = new_materials;
        end


        function plotSN(app)
            material = app.MaterialsListBox.Value;
            rows = strcmp(app.All_Data{:, 1}, material);

            stress = app.All_Data{rows, 3};
            Cycles = app.All_Data{rows, 4};

            loglog(app.UIAxes, Cycles, stress, 'ko-', 'LineWidth', 2);
            xlabel(app.UIAxes, 'Number of Cycles');
            ylabel(app.UIAxes, 'Stress (MPa)');
            title(app.UIAxes, ['S-N Curve for ', material]);
            grid(app.UIAxes, 'on');
        end

        function Nf = fatigue_life_calculator(app, S)
            selected_material = app.MaterialsListBox.Value;

            %Filter data set for selected material

            rows = strcmp(app.All_Data{:, 1}, selected_material);
            stress = app.All_Data{rows, 3}; % Stress Amplitudes for selected material
            cycles = app.All_Data{rows, 4}; % Number of cyles for selected material

            if any(S < min(stress)) || any(S > max(stress))
                warning('Some stress values are outside the known data range. Extrapolation may be inaccurate.');
            end

            % Interpolation
            logNf = interp1(stress, log10(cycles), S, 'linear', 'extrap');
            Nf = 10.^logNf;
        end

        function D = miners_rule_calculator(app, S, C)
            D = 0;
            for i = 1:length(S)
                Nf = fatigue_life_calculator(app, S(i));
                D = D + (C(i) / Nf);
            end
        end
            
        function checkFatigue(app)
            if isempty(app.MaterialsListBox.Value)
                msg = "Please select an material to proceed";
                app.Label.Text = msg;
                return;
            end

            if isempty(app.StressValuesEditField.Value) || isempty(app.StressValuesEditField_2.Value) || isempty(app.StressValuesEditField_3.Value)
                msg = "Please enter appropriate stress values for calculation";
                app.Label.Text  = msg;
                return;
            end
            
            if isempty(app.CyclesValuesEditField_4.Value) || isempty(app.CyclesValuesEditField_5.Value) || isempty(app.CyclesValuesEditField_6.Value)
                msg = "Please enter appropriate cycles values for calculation";
                app.Label.Text = msg;
                return;
            end

            stress1 = app.StressValuesEditField.Value;
            stress2 = app.StressValuesEditField_2.Value;
            stress3 = app.StressValuesEditField_3.Value;

            cycle1 = app.CyclesValuesEditField_4.Value;
            cycle2 = app.CyclesValuesEditField_5.Value;
            cycle3 = app.CyclesValuesEditField_6.Value;

            s_vals = [stress1 stress2 stress3];
            c_vals = [cycle1 cycle2 cycle3];

            D = miners_rule_calculator(app, s_vals, c_vals);

            if D >= 1
                result = sprintf('❌ Fatigue Failure Likely! Damage: %.2f', D*100);
            else
                result = sprintf('✅ Material will survive. Damage: %.2f', D*100);
            end

            app.Label.Text = result;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              %place holder
        end

        % Value changed function: MaterialsListBox
        function MaterialsListBoxValueChanged(app, event)
            plotSN(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            loadMaterialList(app);
            loadMaterialType(app);
        end

        % Button pushed function: CheckFatigueButton
        function CheckFatigueButtonPushed(app, event)
            checkFatigue(app);
        end

        % Value changed function: MaterialTypeDropDown
        function MaterialTypeDropDownValueChanged(app, event)
            match_MT_to_ML(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'S-N Curve')
            xlabel(app.UIAxes, 'Number of Cycles')
            ylabel(app.UIAxes, 'Stress in MPa')
            zlabel(app.UIAxes, 'Z')
            app.MaterialsListBoxLabel = uilabel(app.UIFigure);
            app.MaterialsListBoxLabel.Text = 'Materials';
            app.MaterialsListBox = uilistbox(app.UIFigure);
            app.MaterialsListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialsListBoxValueChanged, true);
            app.MaterialsListBox.Value = {};
            app.StressValuesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StressValuesEditField.Limits = [0 Inf];
            app.StressValuesEditField.AllowEmpty = 'on';
            app.StressValuesEditField.Placeholder = 'Enter ';
            app.StressValuesEditField.Value = [];
            app.FatigueLifeEstimatorLabel = uilabel(app.UIFigure);
            app.FatigueLifeEstimatorLabel.Text = 'Fatigue Life Estimator';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StressValuesLabel = uilabel(app.UIFigure);
            app.StressValuesLabel.Text = 'Stress Values';
            app.StressValuesEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.StressValuesEditField_2.Limits = [0 Inf];
            app.StressValuesEditField_2.AllowEmpty = 'on';
            app.StressValuesEditField_2.Placeholder = 'Enter';
            app.StressValuesEditField_2.Value = [];
            app.StressValuesEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.StressValuesEditField_3.Limits = [0 Inf];
            app.StressValuesEditField_3.AllowEmpty = 'on';
            app.StressValuesEditField_3.Placeholder = 'Enter';
            app.StressValuesEditField_3.Value = [];
            app.CyclesLabel = uilabel(app.UIFigure);
            app.CyclesLabel.Text = 'Cycles';
            app.CyclesValuesEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.CyclesValuesEditField_4.Limits = [0 Inf];
            app.CyclesValuesEditField_4.AllowEmpty = 'on';
            app.CyclesValuesEditField_4.Placeholder = 'Enter';
            app.CyclesValuesEditField_4.Value = [];
            app.CyclesValuesEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.CyclesValuesEditField_5.Limits = [0 Inf];
            app.CyclesValuesEditField_5.AllowEmpty = 'on';
            app.CyclesValuesEditField_5.Placeholder = 'Enter';
            app.CyclesValuesEditField_5.Value = [];
            app.CyclesValuesEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.CyclesValuesEditField_6.Limits = [0 Inf];
            app.CyclesValuesEditField_6.AllowEmpty = 'on';
            app.CyclesValuesEditField_6.Placeholder = 'Enter';
            app.CyclesValuesEditField_6.Value = [];
            app.CheckFatigueButton = uibutton(app.UIFigure, 'push');
            app.CheckFatigueButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFatigueButtonPushed, true);
            app.CheckFatigueButton.Text = 'Check Fatigue';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FullyReversedLoadingLabel = uilabel(app.UIFigure);
            app.FullyReversedLoadingLabel.Text = 'Fully Reversed Loading';
            app.MaterialTypeDropDown = uidropdown(app.UIFigure);
            app.MaterialTypeDropDown.Items = {'All', 'Metal', 'Polymer', 'Ceramic', 'Alloy'};
            app.MaterialTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialTypeDropDownValueChanged, true);
            app.MaterialTypeDropDown.Value = 'All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end